classdef editlicense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddLicenseUIFigure         matlab.ui.Figure
        OptionalSendlicenseinformationtotheassignedemailLabel  matlab.ui.control.Label
        OperationButton            matlab.ui.control.Button
        SendLicenseInfoCheckBox    matlab.ui.control.CheckBox
        ValidUntilDatePicker       matlab.ui.control.DatePicker
        ValidUntilDatePickerLabel  matlab.ui.control.Label
        UserEmailEditField         matlab.ui.control.EditField
        UserEmailEditFieldLabel    matlab.ui.control.Label
        LicenseKeyEditField        matlab.ui.control.EditField
        LicenseKeyEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
        LicenseKey
        AssignedEmail
        ValidUntil
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Intent)
            centerAppWindow(app);
            app.MainApp = MainApp;
            switch Intent
                case 'add'
                    app.LicenseKey = licenseKeygen;
                    app.LicenseKeyEditField.Value = app.LicenseKey;
                case 'edit'
            end
       
        end

        % Button pushed function: OperationButton
        function OperationButtonPushed(app, event)
            app.MainApp.AddedLicense = {app.LicenseKey, app.AssignedEmail, app.ValidUntil, ''};
            app.OperationButton.Icon = 'graphics/loading-icon.gif';
            drawnow;
            addLicense(app.MainApp);
            delete(app);
        end

        % Value changed function: UserEmailEditField
        function UserEmailEditFieldValueChanged(app, event)
            app.AssignedEmail = app.UserEmailEditField.Value;
           
        end

        % Value changed function: ValidUntilDatePicker
        function ValidUntilDatePickerValueChanged(app, event)
            app.ValidUntil = char(datetime(app.ValidUntilDatePicker.Value, Format = 'yyyy-MM-dd'));            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AddLicenseUIFigure = uifigure('Visible', 'off');
            app.AddLicenseUIFigure.Name = 'Add License';
            app.AddLicenseUIFigure.Icon = fullfile(pathToMLAPP, 'graphics', 'repo-key-icon.png');
            app.LicenseKeyEditFieldLabel = uilabel(app.AddLicenseUIFigure);
            app.LicenseKeyEditFieldLabel.Text = 'License Key';
            app.LicenseKeyEditField = uieditfield(app.AddLicenseUIFigure, 'text');
            app.LicenseKeyEditField.Editable = 'off';
            app.UserEmailEditFieldLabel = uilabel(app.AddLicenseUIFigure);
            app.UserEmailEditFieldLabel.Text = 'User Email';
            app.UserEmailEditField = uieditfield(app.AddLicenseUIFigure, 'text');
            app.UserEmailEditField.ValueChangedFcn = createCallbackFcn(app, @UserEmailEditFieldValueChanged, true);
            app.ValidUntilDatePickerLabel = uilabel(app.AddLicenseUIFigure);
            app.ValidUntilDatePickerLabel.Text = 'Valid Until';
            app.ValidUntilDatePicker = uidatepicker(app.AddLicenseUIFigure);
            app.ValidUntilDatePicker.ValueChangedFcn = createCallbackFcn(app, @ValidUntilDatePickerValueChanged, true);
            app.SendLicenseInfoCheckBox = uicheckbox(app.AddLicenseUIFigure);
            app.SendLicenseInfoCheckBox.Text = '';
            app.OperationButton = uibutton(app.AddLicenseUIFigure, 'push');
            app.OperationButton.ButtonPushedFcn = createCallbackFcn(app, @OperationButtonPushed, true);
            app.OperationButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-plus-outline.png');
            app.OperationButton.Text = 'Add License';
            app.OptionalSendlicenseinformationtotheassignedemailLabel = uilabel(app.AddLicenseUIFigure);
            app.OptionalSendlicenseinformationtotheassignedemailLabel.Text = '(Optional) Send license information to the email.';
            % Show the figure after all components are created
            app.AddLicenseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editlicense(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddLicenseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddLicenseUIFigure)
        end
    end
end