classdef licensemanager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        LastRetrieveLabel       matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        LicensesPanel           matlab.ui.container.Panel
        LoadingImage            matlab.ui.control.Image
        LicensesUITable         matlab.ui.control.Table
        SearchLicenseButton     matlab.ui.control.Button
        SearchLicenseEditField  matlab.ui.control.EditField
        RemoveLicenseButton     matlab.ui.control.Button
        EditLicenseButton       matlab.ui.control.Button
        AddLicenseButton        matlab.ui.control.Button
        DatabasePanel           matlab.ui.container.Panel
        RepoCheckImage          matlab.ui.control.Image
        SaveCredentialsButton   matlab.ui.control.Button
        PATImage                matlab.ui.control.Image
        RepoImage               matlab.ui.control.Image
        PATEditField            matlab.ui.control.EditField
        RepositoryEditField     matlab.ui.control.EditField
        RetrieveDatabaseButton  matlab.ui.control.Button
        UpdateDatabaseButton    matlab.ui.control.Button
        CreateDatabaseButton    matlab.ui.control.Button
        ContextMenu             matlab.ui.container.ContextMenu
        PATHelp                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        UserName = ''
        RepoName = ''
        Token = ''
    end
    
    methods (Access = private)
        
        function Status = checkRepoStatus(app)
            Status = false;
            if any(matches({app.RepoName, app.UserName, app.Token}, ''))
                return;
            end
            Status = githubGet(app.UserName, app.RepoName, '', 'status', Token = app.Token);
            app.RepoCheckImage.Visible = 'on';
            if Status
                app.RepoCheckImage.ImageSource = 'graphics/check-circle.png';
                app.CreateDatabaseButton.Enable = 'on';
            else
                app.RepoCheckImage.ImageSource = 'graphics/alert-octagon.png';
                app.CreateDatabaseButton.Enable = 'off';
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
        end

        % Button pushed function: CreateDatabaseButton
        function CreateDatabaseButtonPushed(app, event)
            app.LoadingImage.Visible = 'on';
            drawnow;
            [Status, Details]  = createLicenseDatabase(app.UserName, app.RepoName, app.Token);
            app.LoadingImage.Visible = 'off';
            drawnow;
            if Status
                uialert(app.UIFigure, ['The creation of license database has been created at ', Details.content.html_url], 'Success', Icon = 'success');
            else
                uialert(app.UIFigure, {'The creation of license database has failed.', ...
                    'Make sure a file named ''license-database.csv'' does not already exist in the repository.'},...
                    'Fail', Icon = 'error');
            end
        end

        % Value changed function: RepositoryEditField
        function RepositoryEditFieldValueChanged(app, event)
            app.UserName = extractBefore(event.Value, "/");
            app.RepoName = extractAfter(event.Value, "/");
            checkRepoStatus(app);
        end

        % Value changed function: PATEditField
        function PATEditFieldValueChanged(app, event)
            app.Token = event.Value;
            checkRepoStatus(app);
        end

        % Value changing function: RepositoryEditField
        function RepositoryEditFieldValueChanging(app, event)
            RepositoryEditFieldValueChanged(app, event);
        end

        % Value changing function: PATEditField
        function PATEditFieldValueChanging(app, event)
            PATEditFieldValueChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App License Manager';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'graphics', 'refresh-icon.png');
            app.DatabasePanel = uipanel(app.UIFigure);
            app.DatabasePanel.Title = 'Database';
            app.CreateDatabaseButton = uibutton(app.DatabasePanel, 'push');
            app.CreateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDatabaseButtonPushed, true);
            app.CreateDatabaseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'database-plus-outline.png');
            app.CreateDatabaseButton.Enable = 'off';
            app.CreateDatabaseButton.Tooltip = {'Create Database'};
            app.CreateDatabaseButton.Text = '';
            app.UpdateDatabaseButton = uibutton(app.DatabasePanel, 'push');
            app.UpdateDatabaseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'database-arrow-up-outline.png');
            app.UpdateDatabaseButton.Enable = 'off';
            app.UpdateDatabaseButton.Tooltip = {'Update Database'};
            app.UpdateDatabaseButton.Text = '';
            app.RetrieveDatabaseButton = uibutton(app.DatabasePanel, 'push');
            app.RetrieveDatabaseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'database-refresh-outline.png');
            app.RetrieveDatabaseButton.Enable = 'off';
            app.RetrieveDatabaseButton.Tooltip = {'Retrieve Database'};
            app.RetrieveDatabaseButton.Text = '';
            app.RepositoryEditField = uieditfield(app.DatabasePanel, 'text');
            app.RepositoryEditField.ValueChangedFcn = createCallbackFcn(app, @RepositoryEditFieldValueChanged, true);
            app.RepositoryEditField.ValueChangingFcn = createCallbackFcn(app, @RepositoryEditFieldValueChanging, true);
            app.RepositoryEditField.Tooltip = {'The repo that you created to store the license database of the app'};
            app.RepositoryEditField.Placeholder = 'username/repo-name';
            app.PATEditField = uieditfield(app.DatabasePanel, 'text');
            app.PATEditField.ValueChangedFcn = createCallbackFcn(app, @PATEditFieldValueChanged, true);
            app.PATEditField.ValueChangingFcn = createCallbackFcn(app, @PATEditFieldValueChanging, true);
            app.PATEditField.Tooltip = {'Personal Access Token'};
            app.PATEditField.Placeholder = 'Personal Access Token';
            app.RepoImage = uiimage(app.DatabasePanel);
            app.RepoImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'folder-arrow-up-down-outline.png');
            app.PATImage = uiimage(app.DatabasePanel);
            app.PATImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'shield-key-outline.png');
            app.SaveCredentialsButton = uibutton(app.DatabasePanel, 'push');
            app.SaveCredentialsButton.Icon = fullfile(pathToMLAPP, 'graphics', 'content-save-outline.png');
            app.SaveCredentialsButton.Tooltip = {'Save Credentials'};
            app.SaveCredentialsButton.Text = '';
            app.RepoCheckImage = uiimage(app.DatabasePanel);
            app.RepoCheckImage.Visible = 'off';
            app.RepoCheckImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'check-circle.png');
            app.LicensesPanel = uipanel(app.UIFigure);
            app.LicensesPanel.Title = 'Licenses';
            app.AddLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.AddLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-plus-outline.png');
            app.AddLicenseButton.Tooltip = {'Add License'};
            app.AddLicenseButton.Text = '';
            app.EditLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.EditLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-edit-outline.png');
            app.EditLicenseButton.Tooltip = {'Edit License'};
            app.EditLicenseButton.Text = '';
            app.RemoveLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.RemoveLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-remove-outline.png');
            app.RemoveLicenseButton.Tooltip = {'Delete License'};
            app.RemoveLicenseButton.Text = '';
            app.SearchLicenseEditField = uieditfield(app.LicensesPanel, 'text');
            app.SearchLicenseEditField.Tooltip = {''};
            app.SearchLicenseEditField.Placeholder = 'Search License';
            app.SearchLicenseButton = uibutton(app.LicensesPanel, 'push');
            app.SearchLicenseButton.Icon = fullfile(pathToMLAPP, 'graphics', 'file-search-icon.png');
            app.SearchLicenseButton.Tooltip = {'Search License'};
            app.SearchLicenseButton.Text = '';
            app.LicensesUITable = uitable(app.LicensesPanel);
            app.LicensesUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.LicensesUITable.RowName = {};
            app.LicensesUITable.SelectionType = 'row';
            app.LoadingImage = uiimage(app.LicensesPanel);
            app.LoadingImage.Visible = 'off';
            app.LoadingImage.ImageSource = fullfile(pathToMLAPP, 'graphics', 'loading-icon.gif');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.Placeholder = 'Select App';
            app.DropDown.Value = {};
            app.LastRetrieveLabel = uilabel(app.UIFigure);
            app.LastRetrieveLabel.Text = 'Last retrieved on XXXXXXXXXX';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'graphics', 'progress-clock.png');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.PATHelp = uimenu(app.ContextMenu);
            app.PATHelp.Text = 'What''s this?';
            % Assign app.ContextMenu
            app.PATEditField.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = licensemanager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end